classdef pong < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Pong                          matlab.ui.Figure
        MenuPanel                     matlab.ui.container.Panel
        PlayButton                    matlab.ui.control.Button
        Label_2                       matlab.ui.control.Label
        NickPlayer1EditField          matlab.ui.control.EditField
        Label                         matlab.ui.control.Label
        NickPlayer2EditField          matlab.ui.control.EditField
        HighscoreLabel                matlab.ui.control.Label
        UITable                       matlab.ui.control.Table
        SkillButtonGroup              matlab.ui.container.ButtonGroup
        lowButton                     matlab.ui.control.RadioButton
        middleButton                  matlab.ui.control.RadioButton
        highButton                    matlab.ui.control.RadioButton
        ComputerSwitchLabel           matlab.ui.control.Label
        ComputerSwitch                matlab.ui.control.RockerSwitch
        ControlLabel                  matlab.ui.control.Label
        Player1ArrowUpArrowDownLabel  matlab.ui.control.Label
        Player2wsLabel                matlab.ui.control.Label
        RoundsEditFieldLabel          matlab.ui.control.Label
        RoundsEditField               matlab.ui.control.NumericEditField
        PONGLabel                     matlab.ui.control.Label
        PlayPanel                     matlab.ui.container.Panel
        UIAxes                        matlab.ui.control.UIAxes
        NickPlayer1Label              matlab.ui.control.Label
        NickPlayer2Label              matlab.ui.control.Label
        RoundLabel                    matlab.ui.control.Label
        roundcntLabel                 matlab.ui.control.Label
        breakButton                   matlab.ui.control.Button
        stoppButton                   matlab.ui.control.Button
    end

    
    properties (Access = public)
        x=-4:0.01:4;  % Description
       
      
    end

 
    methods (Access = private)


    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UITable.Data= pongHighscoreRead('pongHighscoreDatabase.csv');  %Liest die Highsocre Daten beim aufstarten der App ein und stellt diese auf dem UiTabel dar.
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            app.NickPlayer1Label.Text=app.NickPlayer1EditField.Value %Take nickname from Menu
            app.NickPlayer2Label.Text=app.NickPlayer2EditField.Value %Take nickname from Menu
            
             app.Pong.Visible='off'  
             pongengine(app)
            %app.MenuPanel.Visible='off'                              %Wechselt die Ansicht
            %app.PlayPanel.Visible='on'
            %app.PlayPanel.Position = [1 1 640 480];                  %Verschiebt die Ansicht.
        
            %run pongengine  %MatlabScript aufrufen
        
           
        end

        % Callback function
        function cosxButtonPushed(app, event)
            cos_pi_x=cos(pi.*app.x);
            plot(app.UIAxes,app.x,cos_pi_x);
        end

        % Value changed function: ComputerSwitch
        function ComputerSwitchValueChanged(app, event)
            
            value = app.ComputerSwitch.Value;
             switch value    
                case 'On'
                 app.NickPlayer2EditField.Visible='On';
                case 'Off'
                 app.NickPlayer2EditField.Visible='Off';
            end
        end

        % Button pushed function: stoppButton
        function stoppButtonPushed(app, event)
            app.MenuPanel.Visible='on'                              %Wechselt die Ansicht
            app.PlayPanel.Visible='off'
            app.PlayPanel.Position = [1 -478 640 480];                 %Verschiebt die Ansicht.
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Pong = uifigure;
            app.Pong.Name = 'Pong';
            app.MenuPanel = uipanel(app.Pong);
            app.MenuPanel.Title = 'Menu';
            app.PlayButton = uibutton(app.MenuPanel, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play!';
            app.Label_2 = uilabel(app.MenuPanel);
            app.Label_2.Text = '';
            app.NickPlayer1EditField = uieditfield(app.MenuPanel, 'text');
            app.NickPlayer1EditField.Value = 'Player1 Name';
            app.Label = uilabel(app.MenuPanel);
            app.Label.Text = '';
            app.NickPlayer2EditField = uieditfield(app.MenuPanel, 'text');
            app.NickPlayer2EditField.Visible = 'off';
            app.NickPlayer2EditField.Value = 'Player2 Name';
            app.HighscoreLabel = uilabel(app.MenuPanel);
            app.HighscoreLabel.Text = 'Highscore';
            app.UITable = uitable(app.MenuPanel);
            app.UITable.ColumnName = {'Rank'; 'Player'; 'Score'};
            app.UITable.RowName = {};
            app.SkillButtonGroup = uibuttongroup(app.MenuPanel);
            app.SkillButtonGroup.Title = 'Skill';
            app.lowButton = uiradiobutton(app.SkillButtonGroup);
            app.lowButton.Text = 'low';
            app.lowButton.Value = true;
            app.middleButton = uiradiobutton(app.SkillButtonGroup);
            app.middleButton.Text = 'middle';
            app.highButton = uiradiobutton(app.SkillButtonGroup);
            app.highButton.Text = 'high';
            app.ComputerSwitchLabel = uilabel(app.MenuPanel);
            app.ComputerSwitchLabel.Text = 'Computer';
            app.ComputerSwitch = uiswitch(app.MenuPanel, 'rocker');
            app.ComputerSwitch.ValueChangedFcn = createCallbackFcn(app, @ComputerSwitchValueChanged, true);
            app.ControlLabel = uilabel(app.MenuPanel);
            app.ControlLabel.Text = 'Control:';
            app.Player1ArrowUpArrowDownLabel = uilabel(app.MenuPanel);
            app.Player1ArrowUpArrowDownLabel.Text = 'Player1: Arrow Up / Arrow Down';
            app.Player2wsLabel = uilabel(app.MenuPanel);
            app.Player2wsLabel.Text = 'Player2: w / s';
            app.RoundsEditFieldLabel = uilabel(app.MenuPanel);
            app.RoundsEditFieldLabel.Text = 'Rounds';
            app.RoundsEditField = uieditfield(app.MenuPanel, 'numeric');
            app.RoundsEditField.Limits = [1 50];
            app.RoundsEditField.ValueDisplayFormat = '%.0f';
            app.RoundsEditField.Value = 7;
            app.PONGLabel = uilabel(app.MenuPanel);
            app.PONGLabel.Text = 'PONG';
            app.PlayPanel = uipanel(app.Pong);
            app.PlayPanel.Title = 'Play!';
            app.UIAxes = uiaxes(app.PlayPanel);
            title(app.UIAxes, 'GameArea')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes.Visible = 'off';
            app.NickPlayer1Label = uilabel(app.PlayPanel);
            app.NickPlayer1Label.Text = 'NickPlayer1';
            app.NickPlayer2Label = uilabel(app.PlayPanel);
            app.NickPlayer2Label.Text = 'NickPlayer2';
            app.RoundLabel = uilabel(app.PlayPanel);
            app.RoundLabel.Text = 'Round:';
            app.roundcntLabel = uilabel(app.PlayPanel);
            app.roundcntLabel.Text = 'roundcnt';
            app.breakButton = uibutton(app.PlayPanel, 'push');
            app.breakButton.Text = 'break';
            app.stoppButton = uibutton(app.PlayPanel, 'push');
            app.stoppButton.ButtonPushedFcn = createCallbackFcn(app, @stoppButtonPushed, true);
            app.stoppButton.Text = 'stopp';
        end
    end

    methods (Access = public)

        % Construct app
        function app = pong

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Pong)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Pong)
        end
    end
end